classdef module2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DViewButton               matlab.ui.control.Button
        TargetTimeTextAreaLabel   matlab.ui.control.Label
        TargetTimeTextArea        matlab.ui.control.TextArea
        FinalTimeTextAreaLabel    matlab.ui.control.Label
        StartTimeTextArea         matlab.ui.control.TextArea
        FinalTimeTextArea         matlab.ui.control.TextArea
        StartTimeTextAreaLabel    matlab.ui.control.Label
        Slider                    matlab.ui.control.Slider
        epochEditFieldLabel_2     matlab.ui.control.Label
        secEditField              matlab.ui.control.NumericEditField
        secEditFieldLabel         matlab.ui.control.Label
        minEditField              matlab.ui.control.NumericEditField
        minEditFieldLabel         matlab.ui.control.Label
        hourEditField             matlab.ui.control.NumericEditField
        hourEditFieldLabel        matlab.ui.control.Label
        degEditField              matlab.ui.control.NumericEditField
        degEditFieldLabel_2       matlab.ui.control.Label
        ElevationMaskButtonGroup  matlab.ui.container.ButtonGroup
        actEM                     matlab.ui.control.RadioButton
        deactEM                   matlab.ui.control.RadioButton
        mLabel                    matlab.ui.control.Label
        alt                       matlab.ui.control.NumericEditField
        Label_16                  matlab.ui.control.Label
        degEditField_2Label       matlab.ui.control.Label
        deglon                    matlab.ui.control.NumericEditField
        deglat                    matlab.ui.control.NumericEditField
        degEditFieldLabel         matlab.ui.control.Label
        Label_15                  matlab.ui.control.Label
        Label_14                  matlab.ui.control.Label
        lonsec                    matlab.ui.control.NumericEditField
        EditField_10Label         matlab.ui.control.Label
        lonmin                    matlab.ui.control.NumericEditField
        EditField_9Label          matlab.ui.control.Label
        londeg                    matlab.ui.control.NumericEditField
        EditField_8Label          matlab.ui.control.Label
        Label_13                  matlab.ui.control.Label
        Label_12                  matlab.ui.control.Label
        latsec                    matlab.ui.control.NumericEditField
        Label_11                  matlab.ui.control.Label
        latmin                    matlab.ui.control.NumericEditField
        Label_10                  matlab.ui.control.Label
        latdeg                    matlab.ui.control.NumericEditField
        Label_9                   matlab.ui.control.Label
        ButtonGroup_2             matlab.ui.container.ButtonGroup
        degButton                 matlab.ui.control.RadioButton
        degminsec                 matlab.ui.control.RadioButton
        EditField_4               matlab.ui.control.NumericEditField
        Label_8                   matlab.ui.control.Label
        EditField_3               matlab.ui.control.NumericEditField
        Label_7                   matlab.ui.control.Label
        EditField_2               matlab.ui.control.NumericEditField
        Label_6                   matlab.ui.control.Label
        EditField                 matlab.ui.control.NumericEditField
        Label_5                   matlab.ui.control.Label
        ButtonGroup               matlab.ui.container.ButtonGroup
        axisset                   matlab.ui.control.RadioButton
        axisauto                  matlab.ui.control.RadioButton
        axisfix                   matlab.ui.control.RadioButton
        GroundTrackPanel          matlab.ui.container.Panel
        SkyPlotPanel              matlab.ui.container.Panel
        plotButton                matlab.ui.control.Button
        WhichDataDropDown         matlab.ui.control.DropDown
        WhichDataDropDownLabel    matlab.ui.control.Label
    end


    properties (Access = public)
        Given = load('C:\Users\Park Wonwoo\MATLAB Drive\우주궤도역학\module2\nav.mat');
        GPSLLA = load('C:\Users\Park Wonwoo\MATLAB Drive\우주궤도역학\module2\GPSLLA.mat');
        QZSSLLA = load('C:\Users\Park Wonwoo\MATLAB Drive\우주궤도역학\module2\QZSSLLA.mat');
        BDSLLA = load('C:\Users\Park Wonwoo\MATLAB Drive\우주궤도역학\module2\BDSLLA.mat');
    end

    properties (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            gx = geoaxes(app.GroundTrackPanel);
            skyplot(app.SkyPlotPanel, nan, nan);
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            if (app.EditField_4.Value >= app.EditField_3.Value) || (app.EditField.Value >= app.EditField_2.Value)
                f = errordlg('''경도 좌측 끝'' 혹은 ''위도 하부 끝'' 값은 각각 ''경도 우측 끝'' 혹은 ''위도 상부 끝'' 값보다 작아야 합니다.','관측범위 설정 오류');
            else
                delete(app.GroundTrackPanel.Children);

                app.TargetTimeTextArea.Enable = 'on';
                app.Slider.Enable = 'on';
                app.Slider.Value = 0;

                gx = geoaxes(app.GroundTrackPanel);
                geolimits(gx, [-90 90], [-180 180]);
                RadarLLA = [app.deglat.Value, app.deglon.Value, app.alt.Value];
                timestep = app.hourEditField.Value*3600 + app.minEditField.Value*60 + app.secEditField.Value;

                if timestep == 0
                    f = msgbox("Timestep은 1sec 보다는 커야합니다. Timestep을 기본값 1sec로 설정합니다.", "Error", "error");
                    app.secEditField.Value = 1;
                    timestep = 1;
                end

                if app.WhichDataDropDown.Value == "GPS"
                    start_t = app.Given.nav.GPS.toc;
                    period = 2*a2period(app.Given.nav.GPS.a);

                    start_t = datetime(start_t);
                    app.StartTimeTextArea.Value = datestr(start_t, 'yyyy-mm-dd HH:MM:SS');
                    app.TargetTimeTextArea.Value = datestr(start_t, 'yyyy-mm-dd HH:MM:SS');
                    final_t = start_t + seconds(period-1);
                    app.FinalTimeTextArea.Value = datestr(final_t, 'yyyy-mm-dd HH:MM:SS');
                    app.Slider.Limits = [0, period-1];
                    app.Slider.MajorTicks = [0:3600:period];

                    for i = 0:1:length(app.Slider.MajorTicks)
                        app.Slider.MajorTickLabels(1,i+1) = cellstr(num2str(i));
                    end

                    g = geoscatter(gx, app.GPSLLA.rangeInLLA(1:timestep:end,1), app.GPSLLA.rangeInLLA(1:timestep:end,2), [], linspace(1,10,length(app.GPSLLA.rangeInLLA(1:timestep:end,1))));
                    c = colorbar(gx);
                    colormap(gx, jet);
                    c.Label.String = "Time lapse";
                    c.Ticks = [0:3600:period];
                    c.TickLabels = [0:1:length(c.Ticks)];

                    ENU = (lla2enu(app.GPSLLA.rangeInLLA(1:timestep:end,:), RadarLLA, 'ellipsoid'))/10^3;
                    ENUsquare= ENU.^2;
                    ENUrange = sqrt(sum(ENUsquare,2));
                    El = rad2deg(asin(ENU(:,3)./ENUrange));
                    Az = rad2deg(acos(ENU(:,2)./sqrt(ENU(:,1).^2 + ENU(:,2).^2)));

                    ind_1 = find(ENU(1:end,1) < 0);
                    Az(ind_1(1:end),1) = 360 - Az(ind_1(1:end),1);
                    ind_2 = find(El(1:end,1) < 0);
                    El(El(1:end,1) < 0) = nan;
                    skyplot_data = horzcat(Az, El);

                    if app.actEM.Value == true
                        ind = find(skyplot_data(1:end,2) < app.degEditField.Value);
                        skyplot_data(ind(1:end),:) = nan;
                    end

                    skyplot(app.SkyPlotPanel, skyplot_data(:,1), skyplot_data(:,2));

                elseif app.WhichDataDropDown.Value == "QZSS"
                    start_t = app.Given.nav.QZSS.toc;
                    period = a2period(app.Given.nav.QZSS.a);

                    start_t = datetime(start_t);
                    app.StartTimeTextArea.Value = datestr(start_t, 'yyyy-mm-dd HH:MM:SS');
                    app.TargetTimeTextArea.Value = datestr(start_t, 'yyyy-mm-dd HH:MM:SS');
                    final_t = start_t + seconds(period-1);
                    app.FinalTimeTextArea.Value = datestr(final_t, 'yyyy-mm-dd HH:MM:SS');
                    app.Slider.Limits = [0, period-1];
                    app.Slider.MajorTicks = [0:3600:period];

                    for i = 0:1:length(app.Slider.MajorTicks)
                        app.Slider.MajorTickLabels(1,i+1) = cellstr(num2str(i));
                    end

                    g = geoscatter(gx, app.QZSSLLA.rangeInLLA(1:timestep:end,1), app.QZSSLLA.rangeInLLA(1:timestep:end,2), [], linspace(1,10,length(app.QZSSLLA.rangeInLLA(1:timestep:end,1))));
                    c = colorbar(gx);
                    colormap(gx, jet);
                    c.Label.String = "Time lapse";
                    c.Ticks = [0:3600:period];
                    c.TickLabels = [0:1:length(c.Ticks)];

                    ENU = (lla2enu(app.QZSSLLA.rangeInLLA(1:timestep:end,:), RadarLLA, 'ellipsoid'))/10^3;
                    ENUsquare= ENU.^2;
                    ENUrange = sqrt(sum(ENUsquare,2));
                    El = rad2deg(asin(ENU(:,3)./ENUrange));
                    Az = rad2deg(acos(ENU(:,2)./sqrt(ENU(:,1).^2 + ENU(:,2).^2)));

                    ind_1 = find(ENU(1:end,1) < 0);
                    Az(ind_1(1:end),1) = 360 - Az(ind_1(1:end),1);
                    ind_2 = find(El(1:end,1) < 0);
                    El(El(1:end,1) < 0) = nan;
                    skyplot_data = horzcat(Az, El);

                    if app.actEM.Value == true
                        ind = find(skyplot_data(1:end,2) < app.degEditField.Value);
                        skyplot_data(ind(1:end),:) = nan;
                    end

                    skyplot(app.SkyPlotPanel, skyplot_data(:,1), skyplot_data(:,2));

                else
                    start_t = app.Given.nav.BDS.toc;
                    period = a2period(app.Given.nav.BDS.a);

                    start_t = datetime(start_t);
                    app.StartTimeTextArea.Value = datestr(start_t, 'yyyy-mm-dd HH:MM:SS');
                    app.TargetTimeTextArea.Value = datestr(start_t, 'yyyy-mm-dd HH:MM:SS');
                    final_t = start_t + seconds(period-1);
                    app.FinalTimeTextArea.Value = datestr(final_t, 'yyyy-mm-dd HH:MM:SS');
                    app.Slider.Limits = [0, period-1];
                    app.Slider.MajorTicks = [0:3600:period];

                    for i = 0:1:length(app.Slider.MajorTicks)
                        app.Slider.MajorTickLabels(1,i+1) = cellstr(num2str(i));
                    end

                    g = geoscatter(gx, app.BDSLLA.rangeInLLA(1:timestep:end,1), app.BDSLLA.rangeInLLA(1:timestep:end,2), [], linspace(1,10,length(app.BDSLLA.rangeInLLA(1:timestep:end,1))));
                    c = colorbar(gx);
                    colormap(gx, jet);
                    c.Label.String = "Time lapse";
                    c.Ticks = [0:3600:period];
                    c.TickLabels = [0:1:length(c.Ticks)];

                    ENU = (lla2enu(app.BDSLLA.rangeInLLA(1:timestep:end,:), RadarLLA, 'ellipsoid'))/10^3;
                    ENUsquare= ENU.^2;
                    ENUrange = sqrt(sum(ENUsquare,2));
                    El = rad2deg(asin(ENU(:,3)./ENUrange));
                    Az = rad2deg(acos(ENU(:,2)./sqrt(ENU(:,1).^2 + ENU(:,2).^2)));

                    ind_1 = find(ENU(1:end,1) < 0);
                    Az(ind_1(1:end),1) = 360 - Az(ind_1(1:end),1);
                    ind_2 = find(El(1:end,1) < 0);
                    El(El(1:end,1) < 0) = nan;
                    skyplot_data = horzcat(Az, El);

                    if app.actEM.Value == true
                        ind = find(skyplot_data(1:end,2) < app.degEditField.Value);
                        skyplot_data(ind(1:end),:) = nan;
                    end

                    skyplot(app.SkyPlotPanel, skyplot_data(:,1), skyplot_data(:,2));
                end

                if app.axisauto.Value == true
                elseif app.axisfix.Value == true
                    geolimits(gx, [-90 90], [-180 180]);
                elseif app.axisset.Value == true
                    geolimits(gx, [app.EditField_4.Value app.EditField_3.Value], [app.EditField.Value app.EditField_2.Value]);
                end
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if app.axisauto.Value == true
                app.EditField.Enable = 'off';
                app.EditField_2.Enable = 'off';
                app.EditField_3.Enable = 'off';
                app.EditField_4.Enable = 'off';
            end

            if app.axisfix.Value == true
                app.EditField.Enable = 'off';
                app.EditField_2.Enable = 'off';
                app.EditField_3.Enable = 'off';
                app.EditField_4.Enable = 'off';
            end

            if app.axisset.Value == true
                app.EditField.Enable = 'on';
                app.EditField_2.Enable = 'on';
                app.EditField_3.Enable = 'on';
                app.EditField_4.Enable = 'on';
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            if app.degButton.Value == true
                app.latdeg.Enable = 'off';
                app.latmin.Enable = 'off';
                app.latsec.Enable = 'off';
                app.londeg.Enable = 'off';
                app.lonmin.Enable = 'off';
                app.lonsec.Enable = 'off';
                app.deglat.Enable = 'on';
                app.deglon.Enable = 'on';
            end

            if app.degminsec.Value == true
                app.latdeg.Enable = 'on';
                app.latmin.Enable = 'on';
                app.latsec.Enable = 'on';
                app.londeg.Enable = 'on';
                app.lonmin.Enable = 'on';
                app.lonsec.Enable = 'on';
                app.deglat.Enable = 'off';
                app.deglon.Enable = 'off';
            end
        end

        % Value changed function: latdeg
        function latdegValueChanged(app, event)
            app.deglat.Value = app.latdeg.Value + app.latmin.Value/60 + app.latsec.Value/3600;
        end

        % Value changed function: latmin
        function latminValueChanged(app, event)
            app.deglat.Value = app.latdeg.Value + app.latmin.Value/60 + app.latsec.Value/3600;
        end

        % Value changed function: latsec
        function latsecValueChanged(app, event)
            app.deglat.Value = app.latdeg.Value + app.latmin.Value/60 + app.latsec.Value/3600;
        end

        % Value changed function: londeg
        function londegValueChanged(app, event)
            app.deglon.Value = app.londeg.Value + app.lonmin.Value/60 + app.lonsec.Value/3600;
        end

        % Value changed function: lonmin
        function lonminValueChanged(app, event)
            app.deglon.Value = app.londeg.Value + app.lonmin.Value/60 + app.lonsec.Value/3600;
        end

        % Value changed function: lonsec
        function lonsecValueChanged(app, event)
            app.deglon.Value = app.londeg.Value + app.lonmin.Value/60 + app.lonsec.Value/3600;
        end

        % Value changed function: deglat
        function deglatValueChanged(app, event)
            app.latdeg.Value = floor(app.deglat.Value);
            app.latmin.Value = floor((app.deglat.Value - app.latdeg.Value) * 60);
            app.latsec.Value = ((app.deglat.Value - app.latdeg.Value ) * 60 - app.latmin.Value ) * 60;
        end

        % Value changed function: deglon
        function deglonValueChanged(app, event)
            app.londeg.Value = floor(app.deglon.Value);
            app.lonmin.Value = floor((app.deglon.Value - app.londeg.Value) * 60);
            app.lonsec.Value = ((app.deglon.Value - app.londeg.Value ) * 60 - app.lonmin.Value ) * 60;
        end

        % Selection changed function: ElevationMaskButtonGroup
        function ElevationMaskButtonGroupSelectionChanged(app, event)
            if app.deactEM.Value == true
                app.degEditField.Enable = 'off';
            end

            if app.actEM.Value == true
                app.degEditField.Enable = 'on';
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            if (app.EditField_4.Value >= app.EditField_3.Value) || (app.EditField.Value >= app.EditField_2.Value)
                f = errordlg('''경도 좌측 끝'' 혹은 ''위도 하부 끝'' 값은 각각 ''경도 우측 끝'' 혹은 ''위도 상부 끝'' 값보다 작아야 합니다.','관측범위 설정 오류');
            else
                delete(app.GroundTrackPanel.Children);
                delete(app.SkyPlotPanel.Children);
                gx = geoaxes(app.GroundTrackPanel);
                RadarLLA = [app.deglat.Value, app.deglon.Value, app.alt.Value];

                if app.WhichDataDropDown.Value == "GPS"
                    start_t = app.Given.nav.GPS.toc;
                    start_t = datetime(start_t);
                    timestep = app.hourEditField.Value*3600 + app.minEditField.Value*60 + app.secEditField.Value;
                    period = 2*a2period(app.Given.nav.GPS.a);
                    app.Slider.Limits = [0, period-1];
                    app.TargetTimeTextArea.Value = datestr(start_t + seconds(floor(app.Slider.Value)), 'yyyy-mm-dd HH:MM:SS');

                    ENU = (lla2enu(app.GPSLLA.rangeInLLA(1:1:end,:), RadarLLA, 'ellipsoid'))/10^3;
                    ENUsquare= ENU.^2;
                    ENUrange = sqrt(sum(ENUsquare,2));
                    El = rad2deg(asin(ENU(:,3)./ENUrange));
                    Az = rad2deg(acos(ENU(:,2)./sqrt(ENU(:,1).^2 + ENU(:,2).^2)));


                    ind_1 = find(ENU(1:end,1) < 0);
                    Az(ind_1(1:end),1) = 360 - Az(ind_1(1:end),1);
                    ind_2 = find(El(1:end,1) < 0);
                    El(ind_2(1:end),1) = nan;
                    Az(ind_2(1:end),1) = nan;
                    skyplot_data = horzcat(Az(1:end), El(1:end));

                    X = NaN(length(skyplot_data)-1,2);
                    real = vertcat(X, [Az(floor(app.Slider.Value)+1, 1), El(floor(app.Slider.Value)+1, 1)]);

                    if app.actEM.Value == true
                        ind = find(real(1:end,2) < app.degEditField.Value);
                        real(ind(1:end),:) = nan;
                        skyplot_data(ind(1:end),:) = nan;
                    end

                    geoscatter(gx, app.GPSLLA.rangeInLLA(1:timestep:end,1), app.GPSLLA.rangeInLLA(1:timestep:end,2));
                    hold(gx);
                    geoscatter(gx, app.GPSLLA.rangeInLLA(floor(app.Slider.Value)+1, 1), app.GPSLLA.rangeInLLA(floor(app.Slider.Value)+1, 2), [20^2], 'r', '*');

                    g = [1, 2];
                    S = skyplot(app.SkyPlotPanel, [skyplot_data(1:end,1), real(1:end,1)], [skyplot_data(1:end,2), real(1:end,2)], GroupData = categorical(g));
                    legend(S, ["Origin","Target"]);

                elseif app.WhichDataDropDown.Value == "QZSS"
                    start_t = app.Given.nav.QZSS.toc;
                    start_t = datetime(start_t);
                    timestep = app.hourEditField.Value*3600 + app.minEditField.Value*60 + app.secEditField.Value;
                    period = a2period(app.Given.nav.QZSS.a);
                    app.Slider.Limits = [0, period-1];
                    app.TargetTimeTextArea.Value = datestr(start_t + seconds(floor(app.Slider.Value)), 'yyyy-mm-dd HH:MM:SS');

                    ENU = (lla2enu(app.QZSSLLA.rangeInLLA(1:1:end,:), RadarLLA, 'ellipsoid'))/10^3;
                    ENUsquare= ENU.^2;
                    ENUrange = sqrt(sum(ENUsquare,2));
                    El = rad2deg(asin(ENU(:,3)./ENUrange));
                    Az = rad2deg(acos(ENU(:,2)./sqrt(ENU(:,1).^2 + ENU(:,2).^2)));


                    ind_1 = find(ENU(1:end,1) < 0);
                    Az(ind_1(1:end),1) = 360 - Az(ind_1(1:end),1);
                    ind_2 = find(El(1:end,1) < 0);
                    El(ind_2(1:end),1) = nan;
                    Az(ind_2(1:end),1) = nan;
                    skyplot_data = horzcat(Az(1:end), El(1:end));

                    X = NaN(length(skyplot_data)-1,2);
                    real = vertcat(X, [Az(floor(app.Slider.Value)+1, 1), El(floor(app.Slider.Value)+1, 1)]);

                    if app.actEM.Value == true
                        ind = find(real(1:end,2) < app.degEditField.Value);
                        real(ind(1:end),:) = nan;
                        skyplot_data(ind(1:end),:) = nan;
                    end

                    geoscatter(gx, app.QZSSLLA.rangeInLLA(1:timestep:end,1), app.QZSSLLA.rangeInLLA(1:timestep:end,2));
                    hold(gx);
                    geoscatter(gx, app.QZSSLLA.rangeInLLA(floor(app.Slider.Value)+1, 1), app.QZSSLLA.rangeInLLA(floor(app.Slider.Value)+1, 2), [20^2], 'r', '*');

                    g = [1, 2];
                    S = skyplot(app.SkyPlotPanel, [skyplot_data(1:end,1), real(1:end,1)], [skyplot_data(1:end,2), real(1:end,2)], GroupData = categorical(g));
                    legend(S, ["Origin","Target"]);

                else
                    start_t = app.Given.nav.BDS.toc;
                    start_t = datetime(start_t);
                    timestep = app.hourEditField.Value*3600 + app.minEditField.Value*60 + app.secEditField.Value;
                    period = a2period(app.Given.nav.BDS.a);
                    app.Slider.Limits = [0, period-1];
                    app.TargetTimeTextArea.Value = datestr(start_t + seconds(floor(app.Slider.Value)), 'yyyy-mm-dd HH:MM:SS');

                    ENU = (lla2enu(app.BDSLLA.rangeInLLA(1:1:end,:), RadarLLA, 'ellipsoid'))/10^3;
                    ENUsquare= ENU.^2;
                    ENUrange = sqrt(sum(ENUsquare,2));
                    El = rad2deg(asin(ENU(:,3)./ENUrange));
                    Az = rad2deg(acos(ENU(:,2)./sqrt(ENU(:,1).^2 + ENU(:,2).^2)));


                    ind_1 = find(ENU(1:end,1) < 0);
                    Az(ind_1(1:end),1) = 360 - Az(ind_1(1:end),1);
                    ind_2 = find(El(1:end,1) < 0);
                    El(ind_2(1:end),1) = nan;
                    Az(ind_2(1:end),1) = nan;
                    skyplot_data = horzcat(Az(1:end), El(1:end));

                    X = NaN(length(skyplot_data)-1,2);
                    real = vertcat(X, [Az(floor(app.Slider.Value)+1, 1), El(floor(app.Slider.Value)+1, 1)]);

                    if app.actEM.Value == true
                        ind = find(real(1:end,2) < app.degEditField.Value);
                        real(ind(1:end),:) = nan;
                        skyplot_data(ind(1:end),:) = nan;
                    end

                    geoscatter(gx, app.BDSLLA.rangeInLLA(1:timestep:end,1), app.BDSLLA.rangeInLLA(1:timestep:end,2));
                    hold(gx);
                    geoscatter(gx, app.BDSLLA.rangeInLLA(floor(app.Slider.Value)+1, 1), app.BDSLLA.rangeInLLA(floor(app.Slider.Value)+1, 2), [20^2], 'r', '*');

                    g = [1, 2];
                    S = skyplot(app.SkyPlotPanel, [skyplot_data(1:end,1), real(1:end,1)], [skyplot_data(1:end,2), real(1:end,2)], GroupData = categorical(g));
                    legend(S, ["Origin","Target"]);
                end

                if app.axisauto.Value == true
                elseif app.axisfix.Value == true
                    geolimits(gx, [-90 90], [-180 180]);
                elseif app.axisset.Value == true
                    geolimits(gx, [app.EditField_4.Value app.EditField_3.Value], [app.EditField.Value app.EditField_2.Value]);
                end
            end

        end

        % Button pushed function: DViewButton
        function DViewButtonPushed(app, event)
            if app.WhichDataDropDown.Value == "GPS"
                startTime = datetime(app.Given.nav.GPS.toc);
                stopTime = startTime + days(1);
                sampleTime = 100;
                sc = satelliteScenario(startTime,stopTime,sampleTime);
                gs = groundStation(sc,app.deglat.Value,app.deglon.Value);

                semiMajorAxis = app.Given.nav.GPS.a;
                eccentricity = app.Given.nav.GPS.e;
                inclination = rad2deg(app.Given.nav.GPS.i);
                rightAscensionOfAscendingNode = rad2deg(app.Given.nav.GPS.OMEGA);
                argumentOfPeriapsis = rad2deg(app.Given.nav.GPS.omega);
                trueAnomaly = rad2deg(-1.43618732164844);

                sat = satellite(sc, semiMajorAxis, eccentricity, inclination, rightAscensionOfAscendingNode, argumentOfPeriapsis,trueAnomaly);

            elseif app.WhichDataDropDown.Value == "QZSS"
                startTime = datetime(app.Given.nav.QZSS.toc);
                stopTime = startTime + days(1);
                sampleTime = 100;
                sc = satelliteScenario(startTime,stopTime,sampleTime);
                gs = groundStation(sc,app.deglat.Value,app.deglon.Value);

                semiMajorAxis = app.Given.nav.QZSS.a;
                eccentricity = app.Given.nav.QZSS.e;
                inclination = rad2deg(app.Given.nav.QZSS.i);
                rightAscensionOfAscendingNode = rad2deg(app.Given.nav.QZSS.OMEGA);
                argumentOfPeriapsis = rad2deg(app.Given.nav.QZSS.omega);
                trueAnomaly = rad2deg(0.511171260680480);

                sat = satellite(sc, semiMajorAxis, eccentricity, inclination, rightAscensionOfAscendingNode, argumentOfPeriapsis,trueAnomaly);

            else
                startTime = datetime(app.Given.nav.BDS.toc);
                stopTime = startTime + days(1);
                sampleTime = 100;
                sc = satelliteScenario(startTime,stopTime,sampleTime);
                gs = groundStation(sc,app.deglat.Value,app.deglon.Value);

                semiMajorAxis = app.Given.nav.BDS.a;
                eccentricity = app.Given.nav.BDS.e;
                inclination = rad2deg(app.Given.nav.BDS.i);
                rightAscensionOfAscendingNode = rad2deg(app.Given.nav.BDS.OMEGA);
                argumentOfPeriapsis = rad2deg(app.Given.nav.BDS.omega);
                trueAnomaly = rad2deg(2.89295774059052);

                sat = satellite(sc, semiMajorAxis, eccentricity, inclination, rightAscensionOfAscendingNode, argumentOfPeriapsis,trueAnomaly);
            end

            access(sat,gs);
            satelliteScenarioViewer(sc, "CameraReferenceFrame", "Inertial");
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WhichDataDropDownLabel = uilabel(app.UIFigure);
            app.WhichDataDropDownLabel.Text = 'Which Data?';
            app.WhichDataDropDown = uidropdown(app.UIFigure);
            app.WhichDataDropDown.Items = {'GPS', 'QZSS', 'BDS'};
            app.WhichDataDropDown.Value = 'GPS';
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.SkyPlotPanel = uipanel(app.UIFigure);
            app.SkyPlotPanel.Title = 'Sky Plot';
            app.GroundTrackPanel = uipanel(app.UIFigure);
            app.GroundTrackPanel.Title = 'Ground Track';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '관측 범위 설정';
            app.axisfix = uiradiobutton(app.ButtonGroup);
            app.axisfix.Text = '좌표축 고정';
            app.axisfix.Value = true;
            app.axisauto = uiradiobutton(app.ButtonGroup);
            app.axisauto.Text = '좌표축 자동';
            app.axisset = uiradiobutton(app.ButtonGroup);
            app.axisset.Text = '좌표축 설정';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Enable = 'off';
            app.Label_5.Text = '경도 좌측 끝(-180~180)';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [-180 180];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Enable = 'off';
            app.EditField.Value = -10;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Enable = 'off';
            app.Label_6.Text = '경도 우측 끝(-180~180)';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [-180 180];
            app.EditField_2.Enable = 'off';
            app.EditField_2.Value = 10;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Enable = 'off';
            app.Label_7.Text = '위도 상부 끝(-90~90)';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Limits = [-90 90];
            app.EditField_3.Enable = 'off';
            app.EditField_3.Value = 10;
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Enable = 'off';
            app.Label_8.Text = '위도 하부 끝(-90~90)';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Limits = [-90 90];
            app.EditField_4.Enable = 'off';
            app.EditField_4.Value = -10;
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = '기지국 위치';
            app.degminsec = uiradiobutton(app.ButtonGroup_2);
            app.degminsec.Text = '도분초';
            app.degminsec.Value = true;
            app.degButton = uiradiobutton(app.ButtonGroup_2);
            app.degButton.Text = 'deg';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '도';
            app.latdeg = uieditfield(app.UIFigure, 'numeric');
            app.latdeg.Limits = [-90 90];
            app.latdeg.ValueChangedFcn = createCallbackFcn(app, @latdegValueChanged, true);
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '분';
            app.latmin = uieditfield(app.UIFigure, 'numeric');
            app.latmin.Limits = [0 60];
            app.latmin.ValueChangedFcn = createCallbackFcn(app, @latminValueChanged, true);
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '초';
            app.latsec = uieditfield(app.UIFigure, 'numeric');
            app.latsec.Limits = [0 60];
            app.latsec.ValueDisplayFormat = '%.3f';
            app.latsec.ValueChangedFcn = createCallbackFcn(app, @latsecValueChanged, true);
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '위도';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '경도';
            app.EditField_8Label = uilabel(app.UIFigure);
            app.EditField_8Label.Text = '도';
            app.londeg = uieditfield(app.UIFigure, 'numeric');
            app.londeg.Limits = [-180 180];
            app.londeg.ValueChangedFcn = createCallbackFcn(app, @londegValueChanged, true);
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Text = '분';
            app.lonmin = uieditfield(app.UIFigure, 'numeric');
            app.lonmin.Limits = [0 60];
            app.lonmin.ValueChangedFcn = createCallbackFcn(app, @lonminValueChanged, true);
            app.EditField_10Label = uilabel(app.UIFigure);
            app.EditField_10Label.Text = '초';
            app.lonsec = uieditfield(app.UIFigure, 'numeric');
            app.lonsec.Limits = [0 60];
            app.lonsec.ValueDisplayFormat = '%.3f';
            app.lonsec.ValueChangedFcn = createCallbackFcn(app, @lonsecValueChanged, true);
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Enable = 'off';
            app.Label_14.Text = '위도';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Enable = 'off';
            app.Label_15.Text = '경도';
            app.degEditFieldLabel = uilabel(app.UIFigure);
            app.degEditFieldLabel.Enable = 'off';
            app.degEditFieldLabel.Text = 'deg';
            app.deglat = uieditfield(app.UIFigure, 'numeric');
            app.deglat.Limits = [-90 90];
            app.deglat.ValueDisplayFormat = '%.8f';
            app.deglat.ValueChangedFcn = createCallbackFcn(app, @deglatValueChanged, true);
            app.deglat.Enable = 'off';
            app.deglon = uieditfield(app.UIFigure, 'numeric');
            app.deglon.Limits = [-180 180];
            app.deglon.ValueDisplayFormat = '%.8f';
            app.deglon.ValueChangedFcn = createCallbackFcn(app, @deglonValueChanged, true);
            app.deglon.Enable = 'off';
            app.degEditField_2Label = uilabel(app.UIFigure);
            app.degEditField_2Label.Enable = 'off';
            app.degEditField_2Label.Text = 'deg';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '고도';
            app.alt = uieditfield(app.UIFigure, 'numeric');
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.ElevationMaskButtonGroup = uibuttongroup(app.UIFigure);
            app.ElevationMaskButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ElevationMaskButtonGroupSelectionChanged, true);
            app.ElevationMaskButtonGroup.Title = 'Elevation Mask';
            app.deactEM = uiradiobutton(app.ElevationMaskButtonGroup);
            app.deactEM.Text = '비활성화';
            app.deactEM.Value = true;
            app.actEM = uiradiobutton(app.ElevationMaskButtonGroup);
            app.actEM.Text = '활성화';
            app.degEditFieldLabel_2 = uilabel(app.UIFigure);
            app.degEditFieldLabel_2.Enable = 'off';
            app.degEditFieldLabel_2.Text = 'deg';
            app.degEditField = uieditfield(app.UIFigure, 'numeric');
            app.degEditField.Limits = [0 90];
            app.degEditField.Enable = 'off';
            app.hourEditFieldLabel = uilabel(app.UIFigure);
            app.hourEditFieldLabel.Text = 'hour';
            app.hourEditField = uieditfield(app.UIFigure, 'numeric');
            app.hourEditField.Limits = [0 Inf];
            app.minEditFieldLabel = uilabel(app.UIFigure);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.UIFigure, 'numeric');
            app.minEditField.Limits = [0 60];
            app.secEditFieldLabel = uilabel(app.UIFigure);
            app.secEditFieldLabel.Text = 'sec';
            app.secEditField = uieditfield(app.UIFigure, 'numeric');
            app.secEditField.Limits = [0 60];
            app.secEditField.Value = 1;
            app.epochEditFieldLabel_2 = uilabel(app.UIFigure);
            app.epochEditFieldLabel_2.Text = 'Time Step';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [0 3 6 9 12 15 18 21 24 27 30 33 36 39 42 45 48 51 54 57 60 63 66 69 72 75 78 81 84 87 90 93 96 100];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Enable = 'off';
            app.StartTimeTextAreaLabel = uilabel(app.UIFigure);
            app.StartTimeTextAreaLabel.Text = 'Start Time';
            app.FinalTimeTextArea = uitextarea(app.UIFigure);
            app.FinalTimeTextArea.Editable = 'off';
            app.StartTimeTextArea = uitextarea(app.UIFigure);
            app.StartTimeTextArea.Editable = 'off';
            app.FinalTimeTextAreaLabel = uilabel(app.UIFigure);
            app.FinalTimeTextAreaLabel.Text = 'Final Time';
            app.TargetTimeTextArea = uitextarea(app.UIFigure);
            app.TargetTimeTextArea.Editable = 'off';
            app.TargetTimeTextArea.Enable = 'off';
            app.TargetTimeTextAreaLabel = uilabel(app.UIFigure);
            app.TargetTimeTextAreaLabel.Enable = 'off';
            app.TargetTimeTextAreaLabel.Text = 'Target Time';
            app.DViewButton = uibutton(app.UIFigure, 'push');
            app.DViewButton.ButtonPushedFcn = createCallbackFcn(app, @DViewButtonPushed, true);
            app.DViewButton.Text = '3D View';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = module2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end